classdef LBP_HOG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        HOGButton        matlab.ui.control.Button
        LBPButton        matlab.ui.control.Button
        HOGLabel_2       matlab.ui.control.Label
        LBPLabel_2       matlab.ui.control.Label
        OriginalImage_4  matlab.ui.control.Image
        HOGLabel         matlab.ui.control.Label
        LBPLabel         matlab.ui.control.Label
        OriginalImage_2  matlab.ui.control.Image
        UIAxes1          matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        Label_2          matlab.ui.control.Label
        TargetImage      matlab.ui.control.Image
        Label            matlab.ui.control.Label
        OriginalImage    matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        % LBP特征提取函数
        function lbpImage = extractLBPFeaturesCustom(~, image)
            [rows, cols] = size(image);
            lbpImage = zeros(rows, cols, 'uint8'); 
            for i = 2:rows-1
                for j = 2:cols-1
                    center = image(i, j);
                    binaryPattern = [image(i-1, j-1) >= center, image(i-1, j) >= center, image(i-1, j+1) >= center, ...
                                     image(i, j+1) >= center, image(i+1, j+1) >= center, image(i+1, j) >= center, ...
                                     image(i+1, j-1) >= center, image(i, j-1) >= center];
                    lbpImage(i, j) = bi2de(binaryPattern, 'left-msb');
                end
            end
        end
        function hogFeatures = extractHOGFeaturesCustom(~, image)
            % Sobel算子计算梯度
            Gx = [-1 0 1; -2 0 2; -1 0 1];
            Gy = Gx';
            gradX = conv2(double(image), Gx, 'same');
            gradY = conv2(double(image), Gy, 'same');
            
            % 计算梯度幅值和方向
            magnitude = sqrt(gradX.^2 + gradY.^2);
            angle = atan2(gradY, gradX);
            
            % 将角度转换为0到180度
            angle = angle * (180 / pi);
            angle(angle < 0) = angle(angle < 0) + 180;
            
            % 分块计算HOG特征
            cellSize = 8;
            [rows, cols] = size(image);
            hogFeatures = zeros(rows, cols);
            for i = 1:cellSize:rows-cellSize
                for j = 1:cellSize:cols-cellSize
                    blockMag = magnitude(i:i+cellSize-1, j:j+cellSize-1);
                    blockAngle = angle(i:i+cellSize-1, j:j+cellSize-1);
                    hist = zeros(1, 9);
                    for k = 1:cellSize
                        for l = 1:cellSize
                            bin = floor(blockAngle(k, l) / 20) + 1;
                            if bin > 9
                                bin = 9;
                            end
                            hist(bin) = hist(bin) + blockMag(k, l);
                        end
                    end
                    % 将直方图值放入特征图中
                    hogFeatures(i:i+cellSize-1, j:j+cellSize-1) = sum(hist);
                end
            end

            hogFeatures = uint8(255 * mat2gray(hogFeatures));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
                grayImg = rgb2gray(img);
                % 使用OTSU方法进行图像分割
                level = graythresh(grayImg);
                TargetImg = imbinarize(grayImg, level);
                
                rgbTargetImg = cat(3, TargetImg * 255, TargetImg * 255, TargetImg * 255);
                app.TargetImage.ImageSource = rgbTargetImg; 

            end
        end

        % Button pushed function: LBPButton
        function LBPButtonPushed(app, event)
            img = app.OriginalImage.ImageSource;
            grayImg = rgb2gray(img);
            TargetImg = app.TargetImage.ImageSource(:,:,1);
            targetImg = app.TargetImage.ImageSource(:,:,1);


            % 计算并显示LBP特征图
            lbpGrayImg = extractLBPFeaturesCustom(app, grayImg);
            lbpTargetImg = extractLBPFeaturesCustom(app, TargetImg);
       
            app.OriginalImage_2.ImageSource = cat(3, lbpGrayImg , lbpGrayImg , lbpGrayImg );
            app.OriginalImage_4.ImageSource = cat(3, lbpTargetImg, lbpTargetImg, lbpTargetImg);


        end

        % Button pushed function: HOGButton
        function HOGButtonPushed(app, event)
            % 获取原始图像和目标图像
            img = app.OriginalImage.ImageSource;
            grayImg = rgb2gray(img);
            level = graythresh(grayImg);
            targetImg = imbinarize(grayImg, level);

            % 计算并显示HOG特征图
            hogGrayImg = extractHOGFeaturesCustom(app, grayImg);
            hogTargetImg = extractHOGFeaturesCustom(app, targetImg);


            mesh(app.UIAxes1,hogGrayImg);
            mesh(app.UIAxes2,hogTargetImg);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.TargetImage = uiimage(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '目标';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '特征提取';
            app.UIAxes2 = uiaxes(app.Panel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.Panel);
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.OriginalImage_2 = uiimage(app.Panel);
            app.LBPLabel = uilabel(app.Panel);
            app.LBPLabel.Text = '原图LBP特征提取';
            app.HOGLabel = uilabel(app.Panel);
            app.HOGLabel.Text = '原图HOG特征提取';
            app.OriginalImage_4 = uiimage(app.Panel);
            app.LBPLabel_2 = uilabel(app.Panel);
            app.LBPLabel_2.Text = '目标LBP特征提取';
            app.HOGLabel_2 = uilabel(app.Panel);
            app.HOGLabel_2.Text = '目标HOG特征提取';
            app.LBPButton = uibutton(app.Panel, 'push');
            app.LBPButton.ButtonPushedFcn = createCallbackFcn(app, @LBPButtonPushed, true);
            app.LBPButton.Text = 'LBP';
            app.HOGButton = uibutton(app.Panel, 'push');
            app.HOGButton.ButtonPushedFcn = createCallbackFcn(app, @HOGButtonPushed, true);
            app.HOGButton.Text = 'HOG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LBP_HOG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end