classdef Main < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ButtonGroup          matlab.ui.container.ButtonGroup
        SureBUtton           matlab.ui.control.Button
        featureButton        matlab.ui.control.RadioButton
        partitionButton      matlab.ui.control.RadioButton
        edgedetectionButton  matlab.ui.control.RadioButton
        filterButton         matlab.ui.control.RadioButton
        zoom_rotateButton    matlab.ui.control.RadioButton
        enhancementButton    matlab.ui.control.RadioButton
        histogramButton      matlab.ui.control.RadioButton
    end

    properties (Access = private)
        selectedop
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SureBUtton
        function SureBUttonPushed(comp, event)
            if comp.featureButton.Value == true
                run LBP_HOG.mlapp
            end
            if comp.partitionButton.Value == true
                run partition.mlapp
            end
            if comp.edgedetectionButton.Value == true
                run edgedetection.mlapp
            end
            if comp.filterButton.Value == true
                run filter.mlapp
            end
            if comp.zoom_rotateButton.Value == true
                run zoom_rotate.mlapp
            end
            if comp.enhancementButton.Value == true
                run enhancement.mlapp
            end
            if comp.histogramButton.Value == true
                run histogram.mlapp
            end
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 557 546];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create ButtonGroup
            comp.ButtonGroup = uibuttongroup(comp);
            comp.ButtonGroup.Title = '数字图像处理大作业';
            comp.ButtonGroup.FontName = 'Consolas';
            comp.ButtonGroup.FontWeight = 'bold';
            comp.ButtonGroup.FontSize = 16;
            comp.ButtonGroup.Position = [180 147 200 254];

            % Create histogramButton
            comp.histogramButton = uiradiobutton(comp.ButtonGroup);
            comp.histogramButton.Text = '灰度直方图';
            comp.histogramButton.Position = [11 203 82 22];
            comp.histogramButton.Value = true;

            % Create enhancementButton
            comp.enhancementButton = uiradiobutton(comp.ButtonGroup);
            comp.enhancementButton.Text = '图像增强';
            comp.enhancementButton.Position = [11 182 70 22];

            % Create zoom_rotateButton
            comp.zoom_rotateButton = uiradiobutton(comp.ButtonGroup);
            comp.zoom_rotateButton.Text = '图像缩放与旋转';
            comp.zoom_rotateButton.Position = [11 161 106 22];

            % Create filterButton
            comp.filterButton = uiradiobutton(comp.ButtonGroup);
            comp.filterButton.Text = '图像加噪与滤波';
            comp.filterButton.Position = [11 140 106 22];

            % Create edgedetectionButton
            comp.edgedetectionButton = uiradiobutton(comp.ButtonGroup);
            comp.edgedetectionButton.Text = '边缘提取';
            comp.edgedetectionButton.Position = [11 119 70 22];

            % Create partitionButton
            comp.partitionButton = uiradiobutton(comp.ButtonGroup);
            comp.partitionButton.Text = '图像分割';
            comp.partitionButton.Position = [11 98 70 22];

            % Create featureButton
            comp.featureButton = uiradiobutton(comp.ButtonGroup);
            comp.featureButton.Text = '特征提取';
            comp.featureButton.Position = [11 77 70 22];

            % Create SureBUtton
            comp.SureBUtton = uibutton(comp.ButtonGroup, 'push');
            comp.SureBUtton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SureBUttonPushed, true);
            comp.SureBUtton.Position = [25 25 130 43];
            comp.SureBUtton.Text = '确定';
        end
    end
end