classdef edgedetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label_2         matlab.ui.control.Label
        GrayImage       matlab.ui.control.Image
        Label           matlab.ui.control.Label
        OriginalImage   matlab.ui.control.Image
        Panel           matlab.ui.container.Panel
        Button          matlab.ui.control.Button
        laplacianImage  matlab.ui.control.Image
        Label_3         matlab.ui.control.Label
        sobelImage      matlab.ui.control.Image
        SobelLabel      matlab.ui.control.Label
        PrewittLabel    matlab.ui.control.Label
        prewittImage    matlab.ui.control.Image
        RobertsLabel    matlab.ui.control.Label
        robertImage     matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function NewImage = roberts(~, f, T)
            [m, n] = size(f);
            NewImage = zeros(m, n);
        
            for i = 2:m-1
                for j = 2:n-1
                    % 计算Roberts梯度
                    Gx = f(i+1, j+1) - f(i, j);
                    Gy = f(i, j+1) - f(i+1, j);
                    NewImage(i, j) = abs(Gx) + abs(Gy);
        
                    % 阈值处理
                    if NewImage(i, j) < T
                        NewImage(i, j) = 0;
                    else
                        NewImage(i, j) = 255;
                    end
                end
            end
            NewImage = uint8(NewImage);
        end


        function NewImage = sobel(~, f, T)
            [m, n] = size(f);
            NewImage = zeros(m, n);
        
            for i = 2:m-1
                for j = 2:n-1
                    % 计算Sobel梯度
                    Gx = f(i-1, j-1) + 2*f(i, j-1) + f(i+1, j-1) - f(i-1, j+1) - 2*f(i, j+1) - f(i+1, j+1);
                    Gy = f(i+1, j-1) + 2*f(i+1, j) + f(i+1, j+1) - f(i-1, j-1) - 2*f(i-1, j) - f(i-1, j+1);
                    NewImage(i, j) = abs(Gx) + abs(Gy);
        
                    % 阈值处理
                    if NewImage(i, j) < T
                        NewImage(i, j) = 0;
                    else
                        NewImage(i, j) = 255;
                    end
                end
            end
            NewImage = uint8(NewImage);
        end
        
        function NewImage = prewitt(~, f, T)
            [m, n] = size(f);
            NewImage = zeros(m, n);
        
            for i = 2:m-1
                for j = 2:n-1
                    % 计算Prewitt梯度
                    Gx = f(i-1, j-1) + f(i, j-1) + f(i+1, j-1) - f(i-1, j+1) - f(i, j+1) - f(i+1, j+1);
                    Gy = f(i+1, j-1) + f(i+1, j) + f(i+1, j+1) - f(i-1, j-1) - f(i-1, j) - f(i-1, j+1);
                    NewImage(i, j) = abs(Gx) + abs(Gy);
        
                    % 阈值处理
                    if NewImage(i, j) < T
                        NewImage(i, j) = 0;
                    else
                        NewImage(i, j) = 255;
                    end
                end
            end
            NewImage = uint8(NewImage);
        end
        function NewImage = laplacian(~, f, T)
            [m, n] = size(f);
            NewImage = zeros(m, n);
        
            for i = 2:m-1
                for j = 2:n-1
                    % 计算Laplacian梯度
                    NewImage(i, j) = 4 * f(i, j) - f(i-1, j) - f(i+1, j) - f(i, j-1) - f(i, j+1);
        
                    % 阈值处理
                    if NewImage(i, j) < T
                        NewImage(i, j) = 0;
                    else
                        NewImage(i, j) = 255;
                    end
                end
            end
            NewImage = uint8(NewImage);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
                
                gray_image = rgb2gray(img);
                app.GrayImage.ImageSource = cat(3, gray_image, gray_image, gray_image);

            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            image = rgb2gray(app.OriginalImage.ImageSource);
            image1 = roberts(app, image, 40);
            image2 = prewitt(app, image, 75);
            image3 = sobel(app, image, 75);
            image4 = laplacian(app, image, 10);
            app.robertImage.ImageSource = cat(3, image1, image1, image1);
            app.prewittImage.ImageSource = cat(3, image2, image2, image2);
            app.sobelImage.ImageSource = cat(3, image3, image3, image3);
            app.laplacianImage.ImageSource = cat(3, image4, image4, image4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '边缘提取';
            app.robertImage = uiimage(app.Panel);
            app.RobertsLabel = uilabel(app.Panel);
            app.RobertsLabel.Text = 'Roberts算子';
            app.prewittImage = uiimage(app.Panel);
            app.PrewittLabel = uilabel(app.Panel);
            app.PrewittLabel.Text = 'Prewitt算子';
            app.SobelLabel = uilabel(app.Panel);
            app.SobelLabel.Text = 'Sobel算子';
            app.sobelImage = uiimage(app.Panel);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '拉普拉斯算子';
            app.laplacianImage = uiimage(app.Panel);
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '边缘提取';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.GrayImage = uiimage(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '灰度图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = edgedetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end