classdef enhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        GrayImage            matlab.ui.control.Image
        OriginalImage        matlab.ui.control.Image
        Panel                matlab.ui.container.Panel
        cSlider_2            matlab.ui.control.Slider
        cSlider_2Label       matlab.ui.control.Label
        bSlider              matlab.ui.control.Slider
        bSliderLabel         matlab.ui.control.Label
        aSlider              matlab.ui.control.Slider
        aSlider_2Label       matlab.ui.control.Label
        ResImage3            matlab.ui.control.Image
        gxybcfxya1Label      matlab.ui.control.Label
        alphaSlider          matlab.ui.control.Slider
        alphaSliderLabel     matlab.ui.control.Label
        ResImage1            matlab.ui.control.Image
        gxyfxytanalphaLabel  matlab.ui.control.Label
        cSlider              matlab.ui.control.Slider
        cSliderLabel         matlab.ui.control.Label
        ResImage2            matlab.ui.control.Image
        gxyclogfxy1Label     matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        % 线性变换
        function NewIMage = linear_enhance(~, image, alpha)
            NewIMage = tan(alpha/180*pi) * double(image);
            NewIMage = uint8(max(0, min(255, NewIMage)));
        end

        % 对数变换
        function NewIMage = log_enhance(~, image, c)
            NewIMage = c * log(1 + double(image));
            NewIMage = uint8(max(0, min(255, NewIMage)));
        end

        % 指数变换
        function NewIMage = exp_enhance(~, image, a, b, c)
            NewIMage = power(b, c * (image - a)) - 1;
            NewIMage = uint8(max(0, min(255, NewIMage)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
                gray_image = rgb2gray(img);
                app.GrayImage.ImageSource = cat(3, gray_image, gray_image, gray_image);
            end
        end

        % Value changed function: alphaSlider
        function alphaSliderValueChanged(app, event)

            
        end

        % Value changing function: alphaSlider
        function alphaSliderValueChanging(app, event)
            changingValue = event.Value;
            input_image = rgb2gray(app.OriginalImage.ImageSource);
            image = linear_enhance(app, input_image, changingValue);
            app.ResImage1.ImageSource = cat(3, image, image, image);
        end

        % Value changing function: cSlider
        function cSliderValueChanging(app, event)
            changingValue = event.Value;
            input_image = rgb2gray(app.OriginalImage.ImageSource);
            image = log_enhance(app, input_image, changingValue);
            app.ResImage2.ImageSource = cat(3, image, image, image);
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            a = event.Value;
            b = app.bSlider.Value;
            c = app.cSlider_2.Value;
            input_image = rgb2gray(app.OriginalImage.ImageSource);
            image = exp_enhance(app, input_image, a, b, c);
            app.ResImage3.ImageSource = cat(3, image, image, image);
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            a = app.aSlider.Value;
            b = event.Value;
            c = app.cSlider_2.Value;
            input_image = rgb2gray(app.OriginalImage.ImageSource);
            image = exp_enhance(app, input_image, a, b, c);
            app.ResImage3.ImageSource = cat(3, image, image, image);
        end

        % Value changing function: cSlider_2
        function cSlider_2ValueChanging(app, event)
            a = app.aSlider.Value;
            b = app.bSlider.Value;
            c = event.Value;
            input_image = rgb2gray(app.OriginalImage.ImageSource);
            image = exp_enhance(app, input_image, a, b, c);
            app.ResImage3.ImageSource = cat(3, image, image, image);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '图像增强';
            app.gxyclogfxy1Label = uilabel(app.Panel);
            app.gxyclogfxy1Label.Interpreter = 'latex';
            app.gxyclogfxy1Label.Text = '对数变换$g(x,y)=c·log[f(x,y)+1]$';
            app.ResImage2 = uiimage(app.Panel);
            app.cSliderLabel = uilabel(app.Panel);
            app.cSliderLabel.Interpreter = 'latex';
            app.cSliderLabel.Text = '$c$';
            app.cSlider = uislider(app.Panel);
            app.cSlider.Limits = [0 90];
            app.cSlider.ValueChangingFcn = createCallbackFcn(app, @cSliderValueChanging, true);
            app.gxyfxytanalphaLabel = uilabel(app.Panel);
            app.gxyfxytanalphaLabel.Interpreter = 'latex';
            app.gxyfxytanalphaLabel.Text = '线性变换$g(x,y)=f(x,y)·tan\alpha$';
            app.ResImage1 = uiimage(app.Panel);
            app.alphaSliderLabel = uilabel(app.Panel);
            app.alphaSliderLabel.Interpreter = 'latex';
            app.alphaSliderLabel.Text = '$\alpha$';
            app.alphaSlider = uislider(app.Panel);
            app.alphaSlider.Limits = [0 90];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaSliderValueChanged, true);
            app.alphaSlider.ValueChangingFcn = createCallbackFcn(app, @alphaSliderValueChanging, true);
            app.gxybcfxya1Label = uilabel(app.Panel);
            app.gxybcfxya1Label.Interpreter = 'latex';
            app.gxybcfxya1Label.Text = '指数变换$g(x,y)=b^{c·[f(x,y)-a]}-1$';
            app.ResImage3 = uiimage(app.Panel);
            app.aSlider_2Label = uilabel(app.Panel);
            app.aSlider_2Label.Interpreter = 'latex';
            app.aSlider_2Label.Text = '$a$';
            app.aSlider = uislider(app.Panel);
            app.aSlider.Limits = [0 180];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.bSliderLabel = uilabel(app.Panel);
            app.bSliderLabel.Interpreter = 'latex';
            app.bSliderLabel.Text = '$b$';
            app.bSlider = uislider(app.Panel);
            app.bSlider.Limits = [0 45];
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.cSlider_2Label = uilabel(app.Panel);
            app.cSlider_2Label.Interpreter = 'latex';
            app.cSlider_2Label.Text = '$c$';
            app.cSlider_2 = uislider(app.Panel);
            app.cSlider_2.Limits = [0 1];
            app.cSlider_2.ValueChangingFcn = createCallbackFcn(app, @cSlider_2ValueChanging, true);
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.GrayImage = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '灰度图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enhancement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end