classdef filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel_4              matlab.ui.container.Panel
        Button_4             matlab.ui.control.Button
        Slider_3             matlab.ui.control.Slider
        Label_4              matlab.ui.control.Label
        Slider_2             matlab.ui.control.Slider
        Label_3              matlab.ui.control.Label
        Panel_3              matlab.ui.container.Panel
        Button_3             matlab.ui.control.Button
        Slider               matlab.ui.control.Slider
        Label_2              matlab.ui.control.Label
        Panel_2              matlab.ui.container.Panel
        D_0Spinner           matlab.ui.control.Spinner
        D_0SpinnerLabel      matlab.ui.control.Label
        Button_2             matlab.ui.control.Button
        Label_7              matlab.ui.control.Label
        ResImage_2           matlab.ui.control.Image
        Panel                matlab.ui.container.Panel
        Button               matlab.ui.control.Button
        sigma_rSpinner       matlab.ui.control.Spinner
        sigma_rSpinnerLabel  matlab.ui.control.Label
        sigma_sSpinner       matlab.ui.control.Spinner
        sigma_sSpinnerLabel  matlab.ui.control.Label
        omegaSpinner         matlab.ui.control.Spinner
        omegaSpinnerLabel    matlab.ui.control.Label
        Label_6              matlab.ui.control.Label
        ResImage             matlab.ui.control.Image
        Label_5              matlab.ui.control.Label
        NoiseImage           matlab.ui.control.Image
        Label                matlab.ui.control.Label
        OriginalImage        matlab.ui.control.Image
    end

    
    methods (Access = private)
         % 双边滤波
        function NewImage = bfilt_rgb(~, image, w, sigma_s, sigma_r)
            image = im2double(image);

            [X, Y] = meshgrid(-w:w, -w:w);

            % 计算空间权重
            Gs = exp(-(X.^2 + Y.^2) / (2 * sigma_s^2));

            dim = size(image);
            NewImage = zeros(dim);

            for i = 1:dim(1)
                for j = 1:dim(2)
                    % 确定作用区域的边界
                    mni = max(i - w, 1);
                    mxi = min(i + w, dim(1));
                    mnj = max(j - w, 1);
                    mxj = min(j + w, dim(2));
                    % 提取该区域的源图像值赋给 I
                    I = image(mni:mxi, mnj:mxj, :); 
                    % 计算灰度临近权值
                    Gr = exp(-(sum((I - image(i, j, :)).^2, 3)) / (2 * sigma_r^2));
                    % W 为 Gr 和 Gs 的乘积, 计算加权平均并赋值给新图像
                    W = Gr .* Gs((mni:mxi) - i + w + 1, (mnj:mxj) - j + w + 1);
                    NewImage(i, j, :) = sum(sum(W .* I, 1), 2) / sum(W(:));
                end
            end
        end
 
        % 理想低通滤波
        function NewImage = ideal_lowpass_filter(~, noise_image, d0)
            [rows, cols, channels] = size(noise_image);
            NewImage = zeros(rows, cols, channels);
            NewImage = uint8(NewImage);
            for c = 1:channels
                % 获取当前通道的灰度图像
                grayscale_channel = noise_image(:, :, c);
                 % 进行傅里叶变换并中心化
                FftImage=fftshift(fft2(double(grayscale_channel)));
                [N,M]=size(FftImage);
                filtered_channel=zeros(N,M);
                % 计算频谱中心
                r1=floor(M/2);r2=floor(N/2);
                for x = 1:M
                    for y = 1:N
                        % 计算频率距离
                        d = sqrt((x - r1)^2 + (y - r2)^2);
                        % 应用理想低通滤波器
                        if d <= d0
                            h = 1; % 低频保留
                        else
                            h = 0; % 高频滤除
                        end
                        % 滤波
                        filtered_channel(y, x) = h * FftImage(y, x);
                    end
                end
                % 逆傅里叶变换并转换为实数
                filtered_channel=real(ifft2(ifftshift(filtered_channel)));
                filtered_channel=uint8(filtered_channel);
                NewImage(:,:,c)=filtered_channel;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            omega = app.omegaSpinner.Value;
            sigma_r = app.sigma_rSpinner.Value;
            sigma_s = app.sigma_sSpinner.Value;
            image = app.NoiseImage.ImageSource;
            app.ResImage.ImageSource = bfilt_rgb(app, image, omega, sigma_s, sigma_r);

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ResImage_2.ImageSource = ideal_lowpass_filter(app, app.NoiseImage.ImageSource,app.D_0Spinner.Value);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            value = app.Slider.Value;
            app.NoiseImage.ImageSource = imnoise(app.OriginalImage.ImageSource, 'salt & pepper', value);


        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            av = app.Slider_2.Value;
            var = app.Slider_3.Value;
            app.NoiseImage.ImageSource = imnoise(app.OriginalImage.ImageSource, 'gaussian', av, var);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原始图像';
            app.NoiseImage = uiimage(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '噪声图像';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '空域滤波';
            app.ResImage = uiimage(app.Panel);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '双边滤波结果';
            app.omegaSpinnerLabel = uilabel(app.Panel);
            app.omegaSpinnerLabel.Interpreter = 'latex';
            app.omegaSpinnerLabel.Text = '$\omega$';
            app.omegaSpinner = uispinner(app.Panel);
            app.omegaSpinner.Value = 6;
            app.sigma_sSpinnerLabel = uilabel(app.Panel);
            app.sigma_sSpinnerLabel.Interpreter = 'latex';
            app.sigma_sSpinnerLabel.Text = '$\sigma_s$';
            app.sigma_sSpinner = uispinner(app.Panel);
            app.sigma_sSpinner.Value = 5;
            app.sigma_rSpinnerLabel = uilabel(app.Panel);
            app.sigma_rSpinnerLabel.Interpreter = 'latex';
            app.sigma_rSpinnerLabel.Text = '$\sigma_r$';
            app.sigma_rSpinner = uispinner(app.Panel);
            app.sigma_rSpinner.Value = 2;
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '空域滤波';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '频域滤波';
            app.ResImage_2 = uiimage(app.Panel_2);
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '理想低通滤波结果';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '频域滤波';
            app.D_0SpinnerLabel = uilabel(app.Panel_2);
            app.D_0SpinnerLabel.Interpreter = 'latex';
            app.D_0SpinnerLabel.Text = '$D_0$';
            app.D_0Spinner = uispinner(app.Panel_2);
            app.D_0Spinner.Value = 50;
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '椒盐噪声';
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '噪声密度';
            app.Slider = uislider(app.Panel_3);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Button_3 = uibutton(app.Panel_3, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '椒盐噪声';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '高斯噪声';
            app.Label_3 = uilabel(app.Panel_4);
            app.Label_3.Text = '均值';
            app.Slider_2 = uislider(app.Panel_4);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_4 = uilabel(app.Panel_4);
            app.Label_4.Text = '方差';
            app.Slider_3 = uislider(app.Panel_4);
            app.Slider_3.Limits = [0 2];
            app.Slider_3.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Value = 0.01;
            app.Button_4 = uibutton(app.Panel_4, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '高斯噪声';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end