classdef histogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Button_2        matlab.ui.control.Button
        Label_5         matlab.ui.control.Label
        Res2Image       matlab.ui.control.Image
        Label_4         matlab.ui.control.Label
        TargetImage     matlab.ui.control.Image
        Button          matlab.ui.control.Button
        Label_3         matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        Label           matlab.ui.control.Label
        GrayImage       matlab.ui.control.Image
        ResImage        matlab.ui.control.Image
        OriginalImage   matlab.ui.control.Image
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxesHistgram  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function matchedImage = histogram_matching(~, inputImage, targetImage)
            % 获取输入图像和目标图像的大小
            [inputHeight, inputWidth] = size(inputImage);
            [targetHeight, targetWidth] = size(targetImage);
            
            % 初始化直方图
            inputHistogram = zeros(256, 1);
            targetHistogram = zeros(256, 1);
            
            % 计算输入图像的直方图
            for x = 1:inputWidth
                for y = 1:inputHeight
                    pixelValue = inputImage(y, x) + 1; % MATLAB索引从1开始
                    inputHistogram(pixelValue) = inputHistogram(pixelValue) + 1;
                end
            end
            
            % 计算目标图像的直方图
            for x = 1:targetWidth
                for y = 1:targetHeight
                    pixelValue = targetImage(y, x) + 1;
                    targetHistogram(pixelValue) = targetHistogram(pixelValue) + 1;
                end
            end
            
            % 计算累积直方图
            inputCumulative = zeros(256, 1);
            targetCumulative = zeros(256, 1);
            
            inputCumulative(1) = inputHistogram(1);
            targetCumulative(1) = targetHistogram(1);
            
            for i = 2:256
                inputCumulative(i) = inputCumulative(i-1) + inputHistogram(i);
                targetCumulative(i) = targetCumulative(i-1) + targetHistogram(i);
            end
            
            % 归一化累积直方图
            inputCumulative = inputCumulative / (inputHeight * inputWidth);
            targetCumulative = targetCumulative / (targetHeight * targetWidth);
            
            % 创建映射表
            mappingTable = zeros(256, 1, 'uint8');
            
            % 执行直方图匹配
            for i = 1:256
                [~, index] = min(abs(targetCumulative - inputCumulative(i)));
                mappingTable(i) = index - 1;
            end
            
            % 初始化匹配后的图像
            matchedImage = zeros(inputHeight, inputWidth, 'uint8');
            
            % 将映射表应用于输入图像
            for x = 1:inputWidth
                for y = 1:inputHeight
                    pixelValue = inputImage(y, x) + 1;
                    matchedImage(y, x) = mappingTable(pixelValue);
                end
            end
        end
        function NewImage = equalization(~, rgbImage)
            
            histgram = imhist(rgbImage);
            [h, w] = size(rgbImage);
            NewImage = zeros(h, w, 'uint8');
            s = zeros(256);
            s(1) = histgram(1);
        
            for t=2:256
                s(t) = s(t-1) + histgram(t);
            end
            
            for x = 1:w
                for y = 1:h
                    NewImage(y,x) = uint8(255*s(rgbImage(y,x)+1)/(w*h));
                end
            end
        end
        

        function histogram = gray_histogram(~, image)
            histogram = zeros(256, 1);
            [height, width] = size(image);

            for x = 1:width
                for y = 1:height
                    pixelValue = image(y, x) + 1;	
                    histogram(pixelValue) = histogram(pixelValue) + 1;
                end
            end
        end
        % 绘制直方图
        function draw_histogram1(app, histogram)
            axesHandle = app.UIAxesHistgram;
            bar(axesHandle, histogram);
        end
        function draw_histogram2(app, histogram)
            axesHandle = app.UIAxes_2;
            bar(axesHandle, histogram);
        end
        function draw_histogram3(app, histogram)
            axesHandle = app.UIAxes_3;
            bar(axesHandle, histogram);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
                
                gray_image = rgb2gray(img);
                app.GrayImage.ImageSource = cat(3, gray_image, gray_image, gray_image);
                histogram = gray_histogram(app, gray_image);
                draw_histogram1(app, histogram);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            gray_image = rgb2gray(app.OriginalImage.ImageSource);
            image=equalization(app, gray_image);
            app.ResImage.ImageSource = cat(3,image,image,image);
            histogram = gray_histogram(app, rgb2gray(app.ResImage.ImageSource));
            draw_histogram2(app, histogram);
        end

        % Image clicked function: TargetImage
        function TargetImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.TargetImage.ImageSource = img;

            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             % 获取原始图像和目标图像
            inputImage = app.OriginalImage.ImageSource;
            targetImage = app.TargetImage.ImageSource;
            
            % 调用手动实现的直方图规格化函数
            matchedImage = histogram_matching(app, inputImage, targetImage);
        
            % 将匹配后的图像显示在UI中
            app.Res2Image.ImageSource = cat(3, matchedImage, matchedImage, matchedImage);
            
            % 计算并绘制匹配后的灰度直方图
            matchedHistogram = gray_histogram(app, matchedImage);
            draw_histogram3(app, matchedHistogram);
        
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button down function: UIAxesHistgram
        function UIAxesHistgramButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesHistgram = uiaxes(app.UIFigure);
            title(app.UIAxesHistgram, '原图灰度直方图')
            xlabel(app.UIAxesHistgram, '灰度值')
            ylabel(app.UIAxesHistgram, '频次')
            zlabel(app.UIAxesHistgram, 'Z')
            app.UIAxesHistgram.ButtonDownFcn = createCallbackFcn(app, @UIAxesHistgramButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '均衡化灰度直方图')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '规定化灰度直方图')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.ResImage = uiimage(app.UIFigure);
            app.GrayImage = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原始图像';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '直方图均衡化';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '灰度图';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '直方图均衡化';
            app.TargetImage = uiimage(app.UIFigure);
            app.TargetImage.ImageClickedFcn = createCallbackFcn(app, @TargetImageClicked, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '规定化参照图片';
            app.Res2Image = uiimage(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '规定化结果';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '直方图匹配';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end