classdef partition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label_2        matlab.ui.control.Label
        GrayImage      matlab.ui.control.Image
        Label          matlab.ui.control.Label
        Panel          matlab.ui.container.Panel
        KButton        matlab.ui.control.Button
        Button         matlab.ui.control.Button
        OTSUButton     matlab.ui.control.Button
        Image_7        matlab.ui.control.Image
        Image_6        matlab.ui.control.Image
        Image_5        matlab.ui.control.Image
        OriginalImage  matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        % OTSU阈值计算函数
        function level = otsuThreshold(~, I)
            counts = imhist(I);
            total = sum(counts);
            sumB = 0;
            wB = 0;
            maximum = 0.0;
            sum1 = dot(0:255, counts);
            for ii = 1:256
                wB = wB + counts(ii);
                if wB == 0
                    continue;
                end
                wF = total - wB;
                if wF == 0
                    break;
                end
                sumB = sumB + (ii - 1) * counts(ii);
                mB = sumB / wB;
                mF = (sum1 - sumB) / wF;
                between = wB * wF * (mB - mF) ^ 2;
                if between >= maximum
                    level = ii - 1;
                    maximum = between;
                end
            end
        end
        
        % 区域生长函数
        function region = regionGrowing(~, I, seed, threshold)
            [rows, cols] = size(I);
            region = false(rows, cols);
            region(seed(1), seed(2)) = true;
            while true
                newRegion = region;
                for r = 1:rows
                    for c = 1:cols
                        if region(r, c)
                            for dr = -1:1
                                for dc = -1:1
                                    rr = r + dr;
                                    cc = c + dc;
                                    if rr > 0 && rr <= rows && cc > 0 && cc <= cols && ~region(rr, cc)
                                        if abs(I(rr, cc) - I(r, c)) < threshold
                                            newRegion(rr, cc) = true;
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
                if isequal(newRegion, region)
                    break;
                end
                region = newRegion;
            end
        end
        
        % K均值聚类函数
        function clusteredImg = kMeansClustering(~, I, k)
            [rows, cols] = size(I);
            data = I(:);
            centroids = linspace(min(data), max(data), k);
            labels = zeros(size(data));
            while true
                oldCentroids = centroids;
                for i = 1:length(data)
                    [~, labels(i)] = min(abs(data(i) - centroids));
                end
                for j = 1:k
                    centroids(j) = mean(data(labels == j));
                end
                if isequal(oldCentroids, centroids)
                    break;
                end
            end
            clusteredImg = reshape(labels, rows, cols);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
                
                gray_image = rgb2gray(img);
                app.GrayImage.ImageSource = cat(3, gray_image, gray_image, gray_image);

            end
        end

        % Button pushed function: OTSUButton
        function OTSUButtonPushed(app, event)
% OTSU 阈值分割
            grayImage = rgb2gray(app.OriginalImage.ImageSource);
            level = app.otsuThreshold(grayImage);
            binaryImg = grayImage > level;
            binaryImg = uint8(binaryImg) * 255;
            app.Image_5.ImageSource = cat(3, binaryImg, binaryImg, binaryImg);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
       % 区域生长
            grayImage = rgb2gray(app.OriginalImage.ImageSource);
            seedPoint = [100, 100]; % 假设种子点
            threshold = 10; % 假设阈值
            region = app.regionGrowing(grayImage, seedPoint, threshold);
            region = uint8(region) * 255;
            app.Image_6.ImageSource = cat(3, region, region, region);
        end

        % Button pushed function: KButton
        function KButtonPushed(app, event)
 % K均值聚类
            grayImage = rgb2gray(app.OriginalImage.ImageSource);
            k = 3; % 假设分成3类
            clusteredImg = app.kMeansClustering(double(grayImage), k);
            clusteredImg = uint8(255 * mat2gray(clusteredImg));
            app.Image_7.ImageSource = cat(3, clusteredImg, clusteredImg, clusteredImg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '图像分割';
            app.Image_5 = uiimage(app.Panel);
            app.Image_6 = uiimage(app.Panel);
            app.Image_7 = uiimage(app.Panel);
            app.OTSUButton = uibutton(app.Panel, 'push');
            app.OTSUButton.ButtonPushedFcn = createCallbackFcn(app, @OTSUButtonPushed, true);
            app.OTSUButton.Text = 'OTSU';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '区域生长';
            app.KButton = uibutton(app.Panel, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.KButton.Text = 'K均值聚类';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.GrayImage = uiimage(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '灰度图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = partition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end