classdef zoom_rotate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        enlargeButton_2  matlab.ui.control.Button
        Slider           matlab.ui.control.Slider
        Label_4          matlab.ui.control.Label
        enlargeButton    matlab.ui.control.Button
        sySpinner        matlab.ui.control.Spinner
        sySpinnerLabel   matlab.ui.control.Label
        sxSpinner        matlab.ui.control.Spinner
        sxSpinnerLabel   matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Label            matlab.ui.control.Label
        rotateImage      matlab.ui.control.Image
        enlargeImage     matlab.ui.control.Image
        OriginalImage    matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function enlargedImage = enlarge(~, input_image, sx, sy)
            [height, width, channels] = size(input_image);
            newWidth = width * sx;
            newHeight = height * sy;
            newWidth = floor(newWidth);
            newHeight = floor(newHeight);
            enlargedImage = uint8(zeros(newHeight, newWidth, channels));
            % 双线性插值
            for x = 1:newWidth
                for y = 1:newHeight
                    
                    px = x / sx;
                    py = y / sy;
                    
                    % 寻找四个最近的像素
                    x1 = max(1, floor(px));
                    x2 = min(x1 + 1, width);
                    y1 = max(1, floor(py));
                    y2 = min(y1 + 1, height);
                    
                    % 计算插值权重
                    a = px - x1;
                    b = py - y1;
                    
                    for c = 1:channels
                        pixelValue = (1 - a) * (1 - b) * double(input_image(y1, x1, c)) + ...
                                     a * (1 - b) * double(input_image(y1, x2, c)) + ...
                                     (1 - a) * b * double(input_image(y2, x1, c)) + ...
                                     a * b * double(input_image(y2, x2, c));
                        enlargedImage(y, x, c) = uint8(round(pixelValue));
                    end
                end
            end
        end

        
        function rotatedImage = myrotate(~, input_image, deg)
            [height, width, ~] = size(input_image);
        
            % 旋转中心
            center_x = width / 2;
            center_y = height / 2;
        
            % 旋转矩阵
            rotation_matrix = [cosd(deg), -sind(deg); sind(deg), cosd(deg)];
        
            rotatedImage = uint8(zeros(height, width, 3));
        
            % 计算旋转后图像的位置
            for y = 1:height
                for x = 1:width
                    % 在原始图像中的坐标
                    pcoord = rotation_matrix * ([x - center_x; y - center_y]) + [center_x; center_y];
                    px = pcoord(1);
                    py = pcoord(2);
        
                    % 双线性插值
                    x1 = floor(px);
                    x2 = ceil(px);
                    y1 = floor(py);
                    y2 = ceil(py);
        
                    if x1 >= 1 && x2 <= width && y1 >= 1 && y2 <= height
                        Q11 = input_image(y1, x1, :);
                        Q12 = input_image(y2, x1, :);
                        Q21 = input_image(y1, x2, :);
                        Q22 = input_image(y2, x2, :);
        
                        x_diff = px - x1;
                        y_diff = py - y1;
        
                        interpolated_pixel = (1 - x_diff) * (1 - y_diff) * double(Q11) + ...
                            x_diff * (1 - y_diff) * double(Q21) + ...
                            (1 - x_diff) * y_diff * double(Q12) + ...
                            x_diff * y_diff * double(Q22);
        
                        rotatedImage(y, x, :) = uint8(interpolated_pixel);
                    end
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
            end
        end

        % Button pushed function: enlargeButton
        function enlargeButtonPushed(app, event)
            sx = app.sxSpinner.Value;
            sy = app.sySpinner.Value;
            image = enlarge(app, app.OriginalImage.ImageSource, sx, sy);
            app.enlargeImage.ImageSource = image;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)

        end

        % Button pushed function: enlargeButton_2
        function enlargeButton_2Pushed(app, event)
            deg = app.Slider.Value;
            image = myrotate(app, app.OriginalImage.ImageSource, deg);
            app.rotateImage.ImageSource = image;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.enlargeImage = uiimage(app.UIFigure);
            app.rotateImage = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '缩放图像';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '旋转图像';
            app.sxSpinnerLabel = uilabel(app.UIFigure);
            app.sxSpinnerLabel.Text = 'sx';
            app.sxSpinner = uispinner(app.UIFigure);
            app.sxSpinner.Step = 0.1;
            app.sySpinnerLabel = uilabel(app.UIFigure);
            app.sySpinnerLabel.Text = 'sy';
            app.sySpinner = uispinner(app.UIFigure);
            app.sySpinner.Step = 0.1;
            app.enlargeButton = uibutton(app.UIFigure, 'push');
            app.enlargeButton.ButtonPushedFcn = createCallbackFcn(app, @enlargeButtonPushed, true);
            app.enlargeButton.Text = '缩放';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '角度';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-180 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.enlargeButton_2 = uibutton(app.UIFigure, 'push');
            app.enlargeButton_2.ButtonPushedFcn = createCallbackFcn(app, @enlargeButton_2Pushed, true);
            app.enlargeButton_2.Text = '旋转';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zoom_rotate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end